classdef S8ScanV3Current < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        reversevideoSwitch         matlab.ui.control.RockerSwitch
        reversevideoSwitchLabel    matlab.ui.control.Label
        ReverseImgSwitch           matlab.ui.control.RockerSwitch
        ReverseImgSwitchLabel      matlab.ui.control.Label
        PostProcessingLabel        matlab.ui.control.Label
        StartButton_2              matlab.ui.control.Button
        ROILabel                   matlab.ui.control.Label
        WidthEditField             matlab.ui.control.NumericEditField
        WidthEditFieldLabel        matlab.ui.control.Label
        HeightEditField            matlab.ui.control.NumericEditField
        HeightEditFieldLabel       matlab.ui.control.Label
        XOffsetEditField           matlab.ui.control.NumericEditField
        XOffsetEditFieldLabel      matlab.ui.control.Label
        YOffsetEditField           matlab.ui.control.NumericEditField
        YOffsetEditFieldLabel      matlab.ui.control.Label
        ScanPreviewSwitch          matlab.ui.control.RockerSwitch
        ScanPreviewSwitchLabel     matlab.ui.control.Label
        pauseScanEditField         matlab.ui.control.NumericEditField
        pauseScanEditFieldLabel    matlab.ui.control.Label
        DeconnectButton            matlab.ui.control.Button
        ScannerLabel               matlab.ui.control.Label
        EtatEditField              matlab.ui.control.EditField
        EtatEditFieldLabel         matlab.ui.control.Label
        PreviousImageButton        matlab.ui.control.Button
        StepByStepLabel            matlab.ui.control.Label
        NextImageButton            matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        SelecteurKnob              matlab.ui.control.DiscreteKnob
        SelecteurKnobLabel         matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        LastPicEditField           matlab.ui.control.NumericEditField
        LastPicEditField_2Label    matlab.ui.control.Label
        StartPicEditField          matlab.ui.control.NumericEditField
        StartPicEditFieldLabel     matlab.ui.control.Label
        EtatpstEditField           matlab.ui.control.NumericEditField
        EtatpstEditFieldLabel      matlab.ui.control.Label
        fileEditField              matlab.ui.control.EditField
        fileEditFieldLabel         matlab.ui.control.Label
        dirEditField               matlab.ui.control.EditField
        dirEditFieldLabel          matlab.ui.control.Label
        DiskListBox                matlab.ui.control.ListBox
        DiskListBoxLabel           matlab.ui.control.Label
        NombreImageEditField       matlab.ui.control.NumericEditField
        NombreImageEditFieldLabel  matlab.ui.control.Label
        StepperOnLamp              matlab.ui.control.Lamp
        StepperOnLampLabel         matlab.ui.control.Label
        newimageLamp               matlab.ui.control.Lamp
        newimageLampLabel          matlab.ui.control.Label
        MovieNameEditField         matlab.ui.control.EditField
        MovieNameEditFieldLabel    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ard % Arduino Uno
        EtatLed % Retro LED current state
        FastSpeed % Speed for Forward or Backword
        LedProjector =      'D8'    % Output of Porjector LED control
        ShutterCam =        'D4'    % output command Cam Shutter
        DirPin =            'D6'    % ouput dir stepper
        PulPin =            'D11'   % output Pul Stepper
        InductSensor =      'D2'    % Input induction sensor Port
        StopStepper                 % autorisation de rotation 
        ClockWise = true;           % Marche arriere rembobinage
        CounterClockWise = false    % Marche avant lecture
        DirRot                      % sens de rotation CW ou CCW
        MovieName                   % nom de film
        StopMotor                   % 1= arret moteur 
        LampeOFF        =    1      % relais inverse
        LampeON         =    0      % relais inverse
        SpeedStepper    =    0.9    % speed stepper vitesse rapide

        vid = videoinput('winvideo', 2, 'MJPG_1920x1080');
        DiskDirScannedPic % 
        RootNameScannedPic
%        pathname %
%        ScannedPic % image en cours de saisie
    end
    
    methods (Access = private)
        
        function StepperMotorHspeed(app)
            app.StepperOnLamp.Color='magenta';
%            valueSelecteur = app.SelecteurKnob.Value;
            writeDigitalPin(app.ard,app.DirPin,app.DirRot);
            writePWMDutyCycle(app.ard,app.PulPin,app.SpeedStepper);
             while app.StopMotor
                         app.StepperOnLamp.Color='red';
                         pause(0.2);
                         app.StepperOnLamp.Color='white';
                         pause(0.2);
            end
            writePWMDutyCycle(app.ard,app.PulPin,0);
        end
        
        function PreviewImage(app)
            hImage = image(app.UIAxes,zeros(1080,1920,3));                  %If image resolution is 1920x1080
            preview(app.vid,hImage);
            if  strcmp (app.ScanPreviewSwitch.Value,'On')
                   pause (app.pauseScanEditField.Value);
            else
                   pause(1);
            end
                                         
            stoppreview(app.vid); 
%            delete(app.vid);           
        end
                
        function AvanceStepperImage(app)

                writeDigitalPin(app.ard,app.DirPin,app.DirRot);             % sens derotation
                writePWMDutyCycle(app.ard,app.PulPin,app.SpeedStepper);     % demarrage du moteur
                app.StepperOnLamp.Color='red';
                if app.DirRot==app.ClockWise
                   pause(1);                                                 % backward degagement de la detection precedente plus long
                else
                   pause(0.5);                                                 % degagement de la detection precedente
                end

%                lightInducSens = readDigitalPin(app.ard,app.InductSensor);
%                InducSensreal = logical(lightInducSens);
%                InducSens = ~InducSensreal ;                                % niveau bas egale detection
                
% init ver                while InducSens == false                                    % pas de detection du disque
%                         lightInducSens = readDigitalPin(app.ard,app.InductSensor);
%                         InducSensreal = logical(lightInducSens);
%                         InducSens = ~InducSensreal ;
%                end
            % Boucle de détection
                while true
                    lightInducSens = readDigitalPin(app.ard, app.InductSensor);
                    if ~logical(lightInducSens) % niveau bas égale détection
                    break;
                    end
                end


                writeDigitalPin(app.ard,app.ShutterCam,1);                  % indication de la detection et sortie de la boucle
                writePWMDutyCycle(app.ard,app.PulPin,0);
                app.StepperOnLamp.Color='white';
        end


        function SaisieImage(app)
            
            app.NombreImageEditField.Value = app.NombreImageEditField.Value+1;
            hImage = image(app.UIAxes,zeros(1080,1920,3)); 

            ScanFileNameJPG = [sprintf('%06d',app.NombreImageEditField.Value),app.MovieNameEditField.Value,'.jpg'];
            app.fileEditField.Value=ScanFileNameJPG;
            diskused=[app.DiskListBox.Value,'\'];
            workingDir = fullfile(diskused,'S8',app.MovieNameEditField.Value);
            app.dirEditField.Value=workingDir;
            mkdir(workingDir,'images')
            workingDirImages = fullfile(workingDir,'images');
            fullname = fullfile(workingDirImages,ScanFileNameJPG);
%            app.vid.ROIposition = [app.XOffsetEditField.Value,app.YOffsetEditField.Value,app.WidthEditField.Value,app.HeightEditField.Value];
            
            src = getselectedsource(app.vid);

            preview(app.vid,hImage);
            if  strcmp (app.ScanPreviewSwitch.Value,'On')
                pause (app.pauseScanEditField.Value);
            else
                pause(0.5);  %pause minimum 
            end
            snapshot = getsnapshot(app.vid);

%            =flipud(snapshot);  % rotation de l image haut bas
            imwrite(snapshot,fullname) ;  % saisie image apres pause test d equilibrage d image
            stoppreview(app.vid);

        end
        
        
        function PostProcessing(app)

            diskused=[app.DiskListBox.Value,'\'];
            workingDir = fullfile(diskused,'S8',app.MovieNameEditField.Value);
            app.dirEditField.Value=workingDir; 
            Videofilename = [app.MovieNameEditField.Value,'.avi'];
            app.fileEditField.Value=Videofilename;
            
            outputVideo = VideoWriter(fullfile(workingDir,Videofilename));
            outputVideo.FrameRate = 18;
            open(outputVideo);


            if app.LastPicEditField.Value==0
                lastPicture=app.NombreImageEditField.Value;
            else
                lastPicture=app.LastPicEditField.Value;
            end
            if app.StartPicEditField.Value==0
                FirstPicture=1;
            else
                FirstPicture=app.StartPicEditField.Value;
            end
            % index=1;  

            for ii = FirstPicture:lastPicture
                if strcmp (app.reversevideoSwitch.Value,'On') % video cree en mode marche arriere
                    index=lastPicture-ii;
                else
                    index=ii;
                end
                
                
                filename = [sprintf('%06d',index),app.MovieNameEditField.Value,'.jpg'];
                if isfile(fullfile(workingDir,'images',filename))
                    img = imread(fullfile(workingDir,'images',filename));
                    img=fliplr(img);
                    if strcmp (app.ReverseImgSwitch.Value,'On') 
                        img=flipud(img);  % rotation de l image haut bas
                    end
                    writeVideo(outputVideo,img);
                    app.EtatpstEditField.Value=index;
%              if index==50
%                  app.EtatpstEditField.Value=ii;
%                  index=1;
%              else
%                  index=index+1;
%             end
                end
            end
            % Finalize the video file.
            close(outputVideo);
        end
        
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ard = arduino();
            app.EtatEditField.Value= ' x-x-x '; 
            app.EtatEditField.BackgroundColor= 'blue'; 

            pause(2);
            app.DirRot=app.ClockWise;
 %          app.ConsigneLabel.Text = '-';
            configurePin(app.ard,app.LedProjector,'DigitalOutput');         % led projecteur Relais1
            configurePin(app.ard,app.ShutterCam,'DigitalOutput');           % shutter Cam
            configurePin(app.ard,app.DirPin,'DigitalOutput');               % Stepper Dir
            configurePin(app.ard,app.PulPin,'PWM');                         % Stepper pul
            configurePin(app.ard,app.InductSensor,'DigitalInput');          % induction sensor opto
            writeDigitalPin(app.ard,app.LedProjector,app.LampeOFF);

            app.MovieName = app.MovieNameEditField.Value ;
            app.StopMotor= true;
            app.EtatEditField.Value= ' - Stop - '; 
            app.SelecteurKnob.Value = 'Stop';
            writeDigitalPin(app.ard,app.ShutterCam,0);       % shutter eteint
            
%           pause(2)
            app.UIAxes.XLim = [0,1920];
            app.UIAxes.YLim = [0,1080];
            pbaspect(app.UIAxes,[1920,1080,1])
                       

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {763, 763};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {373, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: SelecteurKnob
        function SelecteurKnobValueChanged(app, event)
            value = app.SelecteurKnob.Value;
            if strcmp (value,'Stop')
                % position Stop aucune fonction
                closepreview;
                writeDigitalPin(app.ard,app.LedProjector,app.LampeOFF);
                app.ScannerLabel.FontColor='black';
                app.StepByStepLabel.FontColor='black';
                app.StopMotor= false;
                app.EtatEditField.Value= '- Stop -'; 
%               
            elseif   strcmp (value,'Forward')
                % avance rapide
                closepreview;
                writeDigitalPin(app.ard,app.LedProjector,app.LampeOFF);
                app.ScannerLabel.FontColor='black';
                app.StepByStepLabel.FontColor='black';
                app.EtatEditField.Value= 'Avance rapide '; 
                app.DirRot = app.CounterClockWise ; 
                app.StopMotor= true;
                app.StepperMotorHspeed();
%
             elseif strcmp (value,'StepbyStep')
                % fonction Step by Step FW/BW
                closepreview;
                writeDigitalPin(app.ard,app.LedProjector,app.LampeON);
                app.ScannerLabel.FontColor='black';
                app.StepByStepLabel.FontColor='g';
                app.EtatEditField.Value= 'image par image '; 
                app.ScanPreviewSwitch.Value = 'On' ;                              % par defaut step by step include preview
                app.StopMotor= false;
%               
            elseif strcmp (value,'Scan')
                % fonction Scan until stop Scan
                closepreview;
                writeDigitalPin(app.ard,app.LedProjector,app.LampeON); 
%               app.ScannerLabel.FontColor='g';
                app.StepByStepLabel.FontColor='black';                
                app.EtatEditField.Value= 'Scan en cours ';
                app.DirRot = app.CounterClockWise ;
                app.StopMotor= false;              
%                
            elseif strcmp (value,'Backward')
                % retour rapide BW
                closepreview;
                writeDigitalPin(app.ard,app.LedProjector,app.LampeOFF);
                app.ScannerLabel.FontColor='black';
                app.StepByStepLabel.FontColor='black';   
                app.EtatEditField.Value= 'retour rapide '; 
                app.StopMotor= true;
                app.DirRot = app.ClockWise ; 
                app.StepperMotorHspeed();
 %  
            elseif strcmp (value,'Postprocessing')
                % postprocessing 
                closepreview;
                writeDigitalPin(app.ard,app.LedProjector,app.LampeOFF);
                app.EtatEditField.Value= 'Postprocessing ';       
            end    
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
% Mode Step by Step avance image suivante
            valueSelecteur = app.SelecteurKnob.Value;
            if strcmp (valueSelecteur,'StepbyStep')
                app.DirRot=app.CounterClockWise;
                app.AvanceStepperImage;
                app.PreviewImage;
            end
        end

        % Button pushed function: PreviousImageButton
        function PreviousImageButtonPushed(app, event)
% Mode Step by Step retour image precedente
            valueSelecteur = app.SelecteurKnob.Value;
            if strcmp (valueSelecteur,'StepbyStep')
                app.DirRot=app.ClockWise;
                app.AvanceStepperImage;  
                app.PreviewImage;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
% mode Scan Start scan button 
            valueSelecteur = app.SelecteurKnob.Value;
            app.ScannerLabel.FontColor='red';   
            
%            ScanFileNameJPG = [sprintf('%06d',app.NombreImageEditField.Value),app.MovieNameEditField.Value,'.jpg'];
%            app.fileEditField.Value=ScanFileNameJPG;
%            diskused=[app.DiskListBox.Value,'\'];
%            workingDir = fullfile(diskused,'S8',app.MovieNameEditField.Value);
%            app.dirEditField.Value=workingDir;
%            mkdir(workingDir,'images')
%            workingDirImages = fullfile(workingDir,'images');
%            fullname = fullfile(workingDirImages,ScanFileNameJPG);

            while strcmp (valueSelecteur,'Scan')
                app.AvanceStepperImage;
                app.SaisieImage;                          % saisie image
%                imwrite(app.ScannedPic,fullname) ;      % sauvegarde de l image    
%                ScanFileNameJPG = [sprintf('%06d',app.NombreImageEditField.Value),app.MovieNameEditField.Value,'.jpg'];
%                fullname = fullfile(workingDirImages,ScanFileNameJPG);
                valueSelecteur = app.SelecteurKnob.Value;   
            end
            app.ScannerLabel.FontColor='black';    
        end

        % Button pushed function: DeconnectButton
        function DeconnectButtonPushed(app, event)
            stop(app.vid);
            clear app.ard;
            app.StepperOnLamp.Color='White';
            app.DeconnectButton.BackgroundColor='green';   

        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            valueSelecteur = app.SelecteurKnob.Value;
            if strcmp (valueSelecteur,'Postprocessing')
                app.PostProcessingLabel.FontColor= 'red'; % demarrage process
                app.PostProcessingLabel.FontWeight= 'bold' ;
                app.PostProcessing; 
                app.PostProcessingLabel.FontColor= 'black'; % demarrage process
                app.PostProcessingLabel.FontWeight= 'normal' ;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelecteurKnobLabel = uilabel(app.LeftPanel);
            app.SelecteurKnobLabel.Text = 'Selecteur';
            app.SelecteurKnob = uiknob(app.LeftPanel, 'discrete');
            app.SelecteurKnob.Items = {'Stop', 'Forward', 'StepbyStep', 'Scan', 'Backward', 'Postprocessing'};
            app.SelecteurKnob.ValueChangedFcn = createCallbackFcn(app, @SelecteurKnobValueChanged, true);
            app.SelecteurKnob.Value = 'Stop';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NextImageButton = uibutton(app.LeftPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'NextImage';
            app.StepByStepLabel = uilabel(app.LeftPanel);
            app.StepByStepLabel.Text = 'Step By Step';
            app.PreviousImageButton = uibutton(app.LeftPanel, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousImageButtonPushed, true);
            app.PreviousImageButton.Text = 'PreviousImage';
            app.EtatEditFieldLabel = uilabel(app.LeftPanel);
            app.EtatEditFieldLabel.Text = 'Etat';
            app.EtatEditField = uieditfield(app.LeftPanel, 'text');
            app.ScannerLabel = uilabel(app.LeftPanel);
            app.ScannerLabel.Text = 'Scanner';
            app.DeconnectButton = uibutton(app.LeftPanel, 'push');
            app.DeconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DeconnectButtonPushed, true);
            app.DeconnectButton.Text = 'Deconnect';
            app.pauseScanEditFieldLabel = uilabel(app.LeftPanel);
            app.pauseScanEditFieldLabel.Text = 'pauseScan';
            app.pauseScanEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pauseScanEditField.Limits = [0 60];
            app.pauseScanEditField.Value = 5;
            app.ScanPreviewSwitchLabel = uilabel(app.LeftPanel);
            app.ScanPreviewSwitchLabel.Text = 'Scan Preview';
            app.ScanPreviewSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.ScanPreviewSwitch.Value = 'On';
            app.YOffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.YOffsetEditFieldLabel.Text = 'YOffset';
            app.YOffsetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YOffsetEditField.Limits = [0 Inf];
            app.XOffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.XOffsetEditFieldLabel.Text = 'XOffset';
            app.XOffsetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XOffsetEditField.Limits = [0 Inf];
            app.HeightEditFieldLabel = uilabel(app.LeftPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HeightEditField.Limits = [10 Inf];
            app.HeightEditField.Value = 1080;
            app.WidthEditFieldLabel = uilabel(app.LeftPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WidthEditField.Limits = [10 Inf];
            app.WidthEditField.Value = 1920;
            app.ROILabel = uilabel(app.LeftPanel);
            app.ROILabel.Text = 'ROI';
            app.StartButton_2 = uibutton(app.LeftPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.PostProcessingLabel = uilabel(app.LeftPanel);
            app.PostProcessingLabel.Text = 'PostProcessing';
            app.ReverseImgSwitchLabel = uilabel(app.LeftPanel);
            app.ReverseImgSwitchLabel.Text = 'ReverseImg';
            app.ReverseImgSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.reversevideoSwitchLabel = uilabel(app.LeftPanel);
            app.reversevideoSwitchLabel.Text = 'reverse video';
            app.reversevideoSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Preview')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.MovieNameEditFieldLabel = uilabel(app.RightPanel);
            app.MovieNameEditFieldLabel.Text = 'Movie Name';
            app.MovieNameEditField = uieditfield(app.RightPanel, 'text');
            app.MovieNameEditField.Value = 'comclo';
            app.newimageLampLabel = uilabel(app.RightPanel);
            app.newimageLampLabel.Text = 'new image';
            app.newimageLamp = uilamp(app.RightPanel);
            app.StepperOnLampLabel = uilabel(app.RightPanel);
            app.StepperOnLampLabel.Text = 'StepperOn';
            app.StepperOnLamp = uilamp(app.RightPanel);
            app.NombreImageEditFieldLabel = uilabel(app.RightPanel);
            app.NombreImageEditFieldLabel.Text = 'NombreImage';
            app.NombreImageEditField = uieditfield(app.RightPanel, 'numeric');
            app.NombreImageEditField.Limits = [0 Inf];
            app.DiskListBoxLabel = uilabel(app.RightPanel);
            app.DiskListBoxLabel.Text = 'Disk';
            app.DiskListBox = uilistbox(app.RightPanel);
            app.DiskListBox.Items = {'C:', 'D:', 'E:', 'F:', 'G:'};
            app.DiskListBox.Value = 'E:';
            app.dirEditFieldLabel = uilabel(app.RightPanel);
            app.dirEditFieldLabel.Text = 'dir ';
            app.dirEditField = uieditfield(app.RightPanel, 'text');
            app.fileEditFieldLabel = uilabel(app.RightPanel);
            app.fileEditFieldLabel.Text = 'file';
            app.fileEditField = uieditfield(app.RightPanel, 'text');
            app.EtatpstEditFieldLabel = uilabel(app.RightPanel);
            app.EtatpstEditFieldLabel.Text = 'Etatpst';
            app.EtatpstEditField = uieditfield(app.RightPanel, 'numeric');
            app.StartPicEditFieldLabel = uilabel(app.RightPanel);
            app.StartPicEditFieldLabel.Text = 'StartPic';
            app.StartPicEditField = uieditfield(app.RightPanel, 'numeric');
            app.LastPicEditField_2Label = uilabel(app.RightPanel);
            app.LastPicEditField_2Label.Text = 'LastPic';
            app.LastPicEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = S8ScanV3Current

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end